classdef MilkyWay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        ScanningPanel                  matlab.ui.container.Panel
        ScanLengthSlider               matlab.ui.control.Slider
        ScanLengthSliderLabel          matlab.ui.control.Label
        ScanButton                     matlab.ui.control.Button
        AccumulatePanel                matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        AccumulateButton               matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        CentreFrequencySlider          matlab.ui.control.Slider
        CentreFrequencySliderLabel     matlab.ui.control.Label
        FFTSizeDropDown                matlab.ui.control.DropDown
        FFTSizeDropDownLabel           matlab.ui.control.Label
        CalibrationtimeEditField       matlab.ui.control.NumericEditField
        CalibrationtimeEditFieldLabel  matlab.ui.control.Label
        StartCalibrationButton         matlab.ui.control.Button
        FiguresTab                     matlab.ui.container.Tab
        OptionsPanel                   matlab.ui.container.Panel
        DisablesthecheckfortheRTLSDRduringthescanningandaccumulationphaseIncreasesperformancesignifcantlybutappwillbreakshouldtheRTLSDRbecomedisconnectedLabel  matlab.ui.control.Label
        BypassHardwareCheckCheckBox    matlab.ui.control.CheckBox
        SmoothingPanel                 matlab.ui.container.Panel
        SmoothingFrameSlider           matlab.ui.control.Slider
        SmoothingFrameSliderLabel      matlab.ui.control.Label
        SmoothingOrderSlider           matlab.ui.control.Slider
        SmoothingOrderSliderLabel      matlab.ui.control.Label
        ShowHydrogenLineCheckBox       matlab.ui.control.CheckBox
        ApplySmoothingCheckBox         matlab.ui.control.CheckBox
        SavePanel                      matlab.ui.container.Panel
        ChoosePathButton               matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        FileNameDisplay                matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        WarningsLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

  
    properties (Access = public)
        sdr                     % RTL-SDR object
        fftSize     
        avgBg                   % Background FFT vector
        centreFreq              % RTL-SDR centre frequency    
        freq                    % FFT frequency vector
        lastSamples             % For scanning mode, last n FFT results
        window                  % Hanning window
        filePath                % Location to save figures
        fileName                % Name of file
        plotLine                % Whether to show the hydrogen line
        closeReq                % For controlled app closure
        accumData               % Data stored for accumulation loop
        accumCount = 0;         % Number of samples in accumulation vector
        smoothingFrame = 101;   % Range over which to apply smoothing
        smoothingOrder = 3;     % Polynomial order
        bypassHardwareCheck = false;

        PlotLineHandle          % Stores the handle for the live data line
        HydrogenLineHandle      % Handle for the red hydrogen line
        VelocityAxes            % The axis containing the redshift velocity

        targetFreq = 1420.4057; % 21cm line
        sampleRate = 2.4e6      % RTL-SDR sample rate
        isScanning = false;
        isAccumulating = false;
        applySmoothing = true;         % Whether to use smoothing algorithm
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
    
        end

        % Button pushed function: StartCalibrationButton
        function calibrateButtonPress(app, event)
            calibrate(app);
        end

        % Button pushed function: AccumulateButton
        function AccumButtonPress(app, event)
            toggleAccumulate(app);
        end

        % Button pushed function: ScanButton
        function scanButtonPress(app, event)
            toggleScan(app);  
        end

        % Button pushed function: ChoosePathButton
        function chooseFigPath(app, event)
            % Get user to pick the path to save figures to
            prevPath = app.filePath;
            prevPathExist = any(prevPath ~= 0);
            
            app.filePath = uigetdir(title='Choose a Folder');
            
            if ~(all(app.filePath == 0) && prevPathExist)
                updateFileName(app);
                if app.filePath ~=0
                    app.FileNameDisplay.FontColor = 'black';
                end
            else
                app.filePath = prevPath;
            end
        end

        % Button pushed function: SaveButton
        function saveFig(app, event)
            % Saves figure to appropriate file
            if app.filePath ~= 0
                path = fullfile(app.filePath, app.fileName);
                exportgraphics(app.UIAxes, path);
            else
                app.FileNameDisplay.FontColor = 'red';
            end
        end

        % Value changed function: FileNameEditField
        function updateFileName(app, event)
            name = app.FileNameEditField.Value;
            time = string(datetime('now', format='HHmm'));
            app.fileName = strjoin([name, time, ".png"], "");
            
            if app.filePath ~= 0
                app.FileNameDisplay.Text = strjoin(["Filename:", app.fileName]);
            else
                 app.FileNameDisplay.Text = "Please choose a path";
            end
        end

        % Value changed function: ApplySmoothingCheckBox
        function applySmoothingButPress(app, event)
            value = app.ApplySmoothingCheckBox.Value;
            
            app.applySmoothing = value;
        end

        % Value changed function: ShowHydrogenLineCheckBox
        function showHydrogenLineSwitch(app, event)
            value = app.ShowHydrogenLineCheckBox.Value;
            
            app.plotLine = value;
        end

        % Value changed function: SmoothingOrderSlider
        function updateSmoothingOrder(app, event)
            value = app.SmoothingOrderSlider.Value;
            
            app.smoothingOrder = value;
        end

        % Value changed function: SmoothingFrameSlider
        function updateSmoothingFrame(app, event)
            value = app.SmoothingFrameSlider.Value;
            
            if mod(value, 2) == 0
                value = value + 1; % Smoothing frame must be odd
            end

            app.smoothingFrame = value;
        end

        % Value changed function: BypassHardwareCheckCheckBox
        function hardwareCheckButtPress(app, event)
            value = app.BypassHardwareCheckCheckBox.Value;
            
            app.bypassHardwareCheck = value;
        end

        % Button pushed function: ResetButton
        function onAccumReset(app, event)
            % Reset the accumulation loop
            app.accumData = zeros(app.fftSize, 1);
            app.accumCount = 0;
        end

        % Close request function: UIFigure
        function onClose(app, event)
            app.isScanning = false;
            app.isAccumulating = false;
            % if isvalid(app.sdr)
            %     release(app.sdr)
            %     fprintf("Sdr removed");
            % end
            fprintf("Close function Run\n");
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {593, 593};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onClose, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.CalibrationPanel = uipanel(app.SetupTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.StartCalibrationButton = uibutton(app.CalibrationPanel, 'push');
            app.StartCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateButtonPress, true);
            app.StartCalibrationButton.Text = 'Start Calibration';
            app.CalibrationtimeEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CalibrationtimeEditFieldLabel.Text = 'Calibration time';
            app.CalibrationtimeEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CalibrationtimeEditField.Limits = [0 300];
            app.CalibrationtimeEditField.Value = 60;
            app.FFTSizeDropDownLabel = uilabel(app.CalibrationPanel);
            app.FFTSizeDropDownLabel.Text = 'FFT Size';
            app.FFTSizeDropDown = uidropdown(app.CalibrationPanel);
            app.FFTSizeDropDown.Items = {'512', '1024', '2048', '4096'};
            app.FFTSizeDropDown.Value = '4096';
            app.CentreFrequencySliderLabel = uilabel(app.CalibrationPanel);
            app.CentreFrequencySliderLabel.Text = 'Centre Frequency';
            app.CentreFrequencySlider = uislider(app.CalibrationPanel);
            app.CentreFrequencySlider.Limits = [1415 1425];
            app.CentreFrequencySlider.Step = 1;
            app.CentreFrequencySlider.Value = 1420;
            app.AccumulatePanel = uipanel(app.SetupTab);
            app.AccumulatePanel.Title = 'Accumulate';
            app.AccumulateButton = uibutton(app.AccumulatePanel, 'push');
            app.AccumulateButton.ButtonPushedFcn = createCallbackFcn(app, @AccumButtonPress, true);
            app.AccumulateButton.Enable = 'off';
            app.AccumulateButton.Text = 'Accumulate';
            app.ResetButton = uibutton(app.AccumulatePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @onAccumReset, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ScanningPanel = uipanel(app.SetupTab);
            app.ScanningPanel.Title = 'Scanning';
            app.ScanButton = uibutton(app.ScanningPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @scanButtonPress, true);
            app.ScanButton.Enable = 'off';
            app.ScanButton.Text = 'Scan';
            app.ScanLengthSliderLabel = uilabel(app.ScanningPanel);
            app.ScanLengthSliderLabel.Text = 'Scan Length';
            app.ScanLengthSlider = uislider(app.ScanningPanel);
            app.ScanLengthSlider.Limits = [1 50];
            app.ScanLengthSlider.MajorTicks = [1 25 50 75 100];
            app.ScanLengthSlider.MinorTicks = [12.5 37.5 62.5 87.5];
            app.ScanLengthSlider.Step = 1;
            app.ScanLengthSlider.Value = 30;
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.SavePanel = uipanel(app.FiguresTab);
            app.SavePanel.Title = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.SavePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SavePanel, 'text');
            app.FileNameEditField.CharacterLimits = [1 10];
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @updateFileName, true);
            app.FileNameEditField.Placeholder = 'filename';
            app.FileNameEditField.Value = 'fig';
            app.FileNameDisplay = uilabel(app.SavePanel);
            app.FileNameDisplay.Text = 'Please choose a path';
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveFig, true);
            app.SaveButton.Text = 'Save';
            app.ChoosePathButton = uibutton(app.SavePanel, 'push');
            app.ChoosePathButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFigPath, true);
            app.ChoosePathButton.Text = 'Choose Path';
            app.SmoothingPanel = uipanel(app.FiguresTab);
            app.SmoothingPanel.Title = 'Smoothing';
            app.ApplySmoothingCheckBox = uicheckbox(app.SmoothingPanel);
            app.ApplySmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @applySmoothingButPress, true);
            app.ApplySmoothingCheckBox.Text = 'Apply Smoothing';
            app.ApplySmoothingCheckBox.Value = true;
            app.ShowHydrogenLineCheckBox = uicheckbox(app.SmoothingPanel);
            app.ShowHydrogenLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @showHydrogenLineSwitch, true);
            app.ShowHydrogenLineCheckBox.Text = 'Show Hydrogen Line';
            app.ShowHydrogenLineCheckBox.Value = true;
            app.SmoothingOrderSliderLabel = uilabel(app.SmoothingPanel);
            app.SmoothingOrderSliderLabel.Text = 'Smoothing Order';
            app.SmoothingOrderSlider = uislider(app.SmoothingPanel);
            app.SmoothingOrderSlider.Limits = [0 10];
            app.SmoothingOrderSlider.ValueChangedFcn = createCallbackFcn(app, @updateSmoothingOrder, true);
            app.SmoothingOrderSlider.MinorTicks = [1 3 5 7 9];
            app.SmoothingOrderSlider.Step = 1;
            app.SmoothingOrderSlider.Value = 3;
            app.SmoothingFrameSliderLabel = uilabel(app.SmoothingPanel);
            app.SmoothingFrameSliderLabel.Text = 'Smoothing Frame';
            app.SmoothingFrameSlider = uislider(app.SmoothingPanel);
            app.SmoothingFrameSlider.Limits = [0 500];
            app.SmoothingFrameSlider.ValueChangedFcn = createCallbackFcn(app, @updateSmoothingFrame, true);
            app.SmoothingFrameSlider.Step = 1;
            app.SmoothingFrameSlider.Value = 101;
            app.OptionsPanel = uipanel(app.FiguresTab);
            app.OptionsPanel.Title = 'Options';
            app.BypassHardwareCheckCheckBox = uicheckbox(app.OptionsPanel);
            app.BypassHardwareCheckCheckBox.ValueChangedFcn = createCallbackFcn(app, @hardwareCheckButtPress, true);
            app.BypassHardwareCheckCheckBox.Text = 'Bypass Hardware Check';
            app.DisablesthecheckfortheRTLSDRduringthescanningandaccumulationphaseIncreasesperformancesignifcantlybutappwillbreakshouldtheRTLSDRbecomedisconnectedLabel = uilabel(app.OptionsPanel);
            app.DisablesthecheckfortheRTLSDRduringthescanningandaccumulationphaseIncreasesperformancesignifcantlybutappwillbreakshouldtheRTLSDRbecomedisconnectedLabel.Text = 'Disables the check for the RTL-SDR during the scanning and accumulation phase. Increases performance signifcantly, but app will break should the RTL-SDR become disconnected.';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Frequency (MHz)')
            ylabel(app.UIAxes, 'Relative Intensity')
            zlabel(app.UIAxes, 'Z')
            app.WarningsLabel = uilabel(app.RightPanel);
            app.WarningsLabel.Visible = 'off';
            app.WarningsLabel.Text = 'Warnings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MilkyWay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end